classdef ABRapp_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        DataPanel                     matlab.ui.container.Panel
        DatasetsloadedListBox         matlab.ui.control.ListBox
        DatasetsloadedListBoxLabel    matlab.ui.control.Label
        SaveTablesButton              matlab.ui.control.Button
        SavePlotsButton               matlab.ui.control.Button
        LoadDataButton                matlab.ui.control.Button
        PlotOptionsPanel              matlab.ui.container.Panel
        WaveIareaEditField            matlab.ui.control.NumericEditField
        WaveIareaEditFieldLabel       matlab.ui.control.Label
        PlotwaveIareaCheckBox         matlab.ui.control.CheckBox
        LineWidthSpinner              matlab.ui.control.Spinner
        LineWidthSpinnerLabel         matlab.ui.control.Label
        C3Field                       matlab.ui.control.EditField
        C2Field                       matlab.ui.control.EditField
        C1Field                       matlab.ui.control.EditField
        Color3Button                  matlab.ui.control.Button
        Color2Button                  matlab.ui.control.Button
        Color1Button                  matlab.ui.control.Button
        AmplitudeEditField            matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel       matlab.ui.control.Label
        LatencyEditField              matlab.ui.control.NumericEditField
        LatencyEditFieldLabel         matlab.ui.control.Label
        WaveformplottedSpinner        matlab.ui.control.Spinner
        WaveformplottedSpinnerLabel   matlab.ui.control.Label
        PlotBaselineCheckBox          matlab.ui.control.CheckBox
        LinkaxesCheckBox              matlab.ui.control.CheckBox
        DataselectedEditField         matlab.ui.control.EditField
        DataselectedEditFieldLabel    matlab.ui.control.Label
        EndTimepointEditField         matlab.ui.control.NumericEditField
        EndTimepointEditFieldLabel    matlab.ui.control.Label
        BeginTimepointEditField       matlab.ui.control.NumericEditField
        BeginTimepointEditFieldLabel  matlab.ui.control.Label
        WaveIPointsCheckBox           matlab.ui.control.CheckBox
        FigLegendsCheckBox            matlab.ui.control.CheckBox
        RightPanel                    matlab.ui.container.Panel
        TabGroup2                     matlab.ui.container.TabGroup
        IndWFsTab                     matlab.ui.container.Tab
        CompTab                       matlab.ui.container.Tab
        SaveButton                    matlab.ui.control.Button
        UIAxes5                       matlab.ui.control.UIAxes
        UITable                       matlab.ui.control.Table
        TabGroup                      matlab.ui.container.TabGroup
        OverlayTab                    matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        SelectedWaveTab               matlab.ui.container.Tab
        UIAxes4                       matlab.ui.control.UIAxes
        WaveIamp                      matlab.ui.container.Tab
        UIAxes2                       matlab.ui.control.UIAxes
        WaveIlatTab                   matlab.ui.container.Tab
        UIAxes3                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        dataFolder % location of data files and where to save data
        allData % struct of all loaded ABR data
        activeData % struct of data selected for table/plots
        activeName % name of active data set
        idxBegin %start idx for x axis
        idxEnd %end idc for x axis
        allPeaks %transformed waveform data for plotting
        waveforms %transformed data for plotting
        t %time series for x axis
        C1 = [0 0 1] %color for line 1 of compare plot
        C2 = [1 0 1] %color for line 2 of compare plot
        C3 = [0 0 0]%color for line 3 of compare plot
    end
    
    
    methods (Access = private)
    
        function updatePlotData(app)
            %create time series
            n = numel(app.activeData.Waveforms(:,1));
            app.t = 1:1:n;
            app.t = app.t*0.04096;
            
            % Get figure options
            if app.BeginTimepointEditField.Value == 0
                app.idxBegin = 1;
            else
                app.idxBegin = find(abs(app.t-app.BeginTimepointEditField.Value)<0.02);
            end

            if app.EndTimepointEditField.Value == 12
                app.idxEnd = n;
            else
                app.idxEnd = find(abs(app.t-app.EndTimepointEditField.Value)<0.02);
            end
            
            %Plot data - code from generateFigs.m - need to add legends
            %transform data for easier plotting
            %app.waveforms(1).(app.activeData.Name) = app.activeData.Waveforms;
            %app.allPeaks = structfun(@table2array, app.waveforms, 'UniformOutput', false);
            %app.allPeaks = table2array(app.activeData.Waveforms);
            %app.allPeaks.(app.activeData.Name) = (app.allPeaks.(app.activeData.Name)).*1000000;
            %app.allPeaks = app.allPeaks.*1000000;
            app.allPeaks = app.activeData.Waveforms;
            %[~,numWaves] = size(app.allPeaks.(app.activeData.Name));
            [~,numWaves] = size(app.allPeaks);
            app.WaveformplottedSpinner.Limits = [1 numWaves];
            
        end
        
        %% update table
        function refreshTable(app)
            %Populate wave I data table
            dataTable = app.activeData.waveIdata(:, {'Stimulus level', 'N1 amplitude (µV)', ...
                'P1 amplitude (µV)', 'Wave I amplitude (µV)', 'Wave I latency (ms)', ...
                'AUC wave I', 'Wave I width'});
            app.UITable.Data = dataTable; %display data in table
        end
        
        %% each stimulus level on individual axis (tiled layout)
        function updateTile(app)
            %subplot for each stimulus level
            %ignore 'specify UIAxes handle' error
            [~,cls] = size(app.allPeaks);
            indLayout = tiledlayout(app.IndWFsTab, cls, 1);
            title(indLayout, app.activeName);
            for wv = 1:cls
                ax = nexttile(indLayout);
                plot(ax, app.t(app.idxBegin:app.idxEnd), ...
                    app.allPeaks(app.idxBegin:app.idxEnd, wv), 'LineWidth', app.LineWidthSpinner.Value);
                if app.WaveIPointsCheckBox.Value == true
                    hold(ax, 'on')
                    plot(ax, app.t(table2array(app.activeData.waveIdata(wv,2))), ... %N1 index
                        (table2array(app.activeData.waveIdata(wv,1))), "ro") %N1 amp
                    hold(ax, 'on')
                    plot(ax, app.t(table2array(app.activeData.waveIdata(wv,4))), ... %P1 index
                        (table2array(app.activeData.waveIdata(wv,3))), "bo") %P1 amp
                    hold(ax, 'on')
                    plot(ax, app.t(table2array(app.activeData.waveIdata(wv,9))), ... %preN1 index
                        (table2array(app.activeData.waveIdata(wv,8))), 'go') %preN1 amp
                    hold(ax, 'off')
                end
                if app.PlotBaselineCheckBox.Value == true
                    hold(ax, 'on')
                    yline(ax, table2array(app.activeData.waveIdata(wv, {'Baseline'})), 'k--');
                end
                xlim(ax, [app.BeginTimepointEditField.Value app.EndTimepointEditField.Value]);
                %ylabel(ax, app.waveforms.(app.activeData.Name).Properties.VariableNames{wv});
                ylabel(ax, app.activeData.stimlevs{wv});
                if wv ~= cls
                    xticklabels(ax, '');
                end
                if app.LinkaxesCheckBox.Value == true
                    %set all axes to same ylim if desired
                    ylim(ax, [-2.5 2.5]); %eventually add option for user to provide ylims
                end
            end
            indLayout.Padding = 'compact';
            indLayout.TileSpacing = 'compact';
            xlabel(indLayout, 'Latency (ms)');
            %ylabel(indLayout, 'Amplitude (µV)');
        end
        
        %% overlayed waveform plot
        function updateOverlay(app)

            %all waveforms for one frequency overlayed
            app.UIAxes.Title.String = app.activeName;
%             plot(app.UIAxes, app.t(app.idxBegin:app.idxEnd),...
%                 app.allPeaks.(app.activeData.Name)(app.idxBegin:app.idxEnd, :))
            plot(app.UIAxes, app.t(app.idxBegin:app.idxEnd),...
                app.allPeaks(app.idxBegin:app.idxEnd, :), 'LineWidth', app.LineWidthSpinner.Value);
            app.UIAxes.XLim = [app.BeginTimepointEditField.Value app.EndTimepointEditField.Value];
            %plot wave I points if desired
            if app.WaveIPointsCheckBox.Value == true
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, app.t(table2array(app.activeData.waveIdata(:,2))), ... %N1 index
                    (table2array(app.activeData.waveIdata(:,1))), "ro") %N1 amp
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, app.t(table2array(app.activeData.waveIdata(:,4))), ... %P1 index
                    (table2array(app.activeData.waveIdata(:,3))), "bo") %P1 amp
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, app.t(table2array(app.activeData.waveIdata(:,9))), ... %preN1 index
                    (table2array(app.activeData.waveIdata(:,8))), 'go') %preN1 amp
                hold(app.UIAxes, 'off')
            end
            %figure out specifying DisplayNames in legend
            legend(app.UIAxes, 'Location', 'northeast');

            if app.FigLegendsCheckBox.Value == true
                legend(app.UIAxes, 'show');
            elseif app.FigLegendsCheckBox.Value == false
                legend(app.UIAxes, 'off');
            end
        end
        
        %% single waveform plot
        function updateSingle(app)
            %single waveform plot
            selWv = app.WaveformplottedSpinner.Value;
            app.UIAxes4.Title.String = strcat(app.activeName, " ",...
                table2array(app.activeData.waveIdata(selWv, 'Stimulus level')));
%             plot(app.UIAxes4, app.t(app.idxBegin:app.idxEnd),...
%                 app.allPeaks.(app.activeData.Name)(app.idxBegin:app.idxEnd, selWv),...
%                 "Color", "k", "LineWidth", 1, 'ButtonDownFcn', @mouseClick);
            plot(app.UIAxes4, app.t(app.idxBegin:app.idxEnd),...
                app.allPeaks(app.idxBegin:app.idxEnd, selWv),...
                "-o", "LineWidth", 1, "Color", "k", "MarkerSize", 4,...
                'ButtonDownFcn', @mouseClick);
            app.UIAxes4.XLim = [app.BeginTimepointEditField.Value app.EndTimepointEditField.Value];
            %plot wave I points if desired
            if app.WaveIPointsCheckBox.Value == true
                hold(app.UIAxes4, 'on')
                plot(app.UIAxes4, app.t(table2array(app.activeData.waveIdata(selWv,2))), ... %N1 index
                    (table2array(app.activeData.waveIdata(selWv,1))), "ro",...
                    'MarkerFaceColor', "r", 'ButtonDownFcn', @mouseClick); %N1 amp
                hold(app.UIAxes4, 'on')
                plot(app.UIAxes4, app.t(table2array(app.activeData.waveIdata(selWv,4))), ... %P1 index
                    (table2array(app.activeData.waveIdata(selWv,3))), "bo",...
                    'MarkerFaceColor', "b", 'ButtonDownFcn', @mouseClick); %P1 amp
                hold(app.UIAxes4, 'on')
                plot(app.UIAxes4, app.t(table2array(app.activeData.waveIdata(selWv,9))), ... %preN1 index
                    (table2array(app.activeData.waveIdata(selWv,8))), 'go', ...
                    'MarkerFaceColor', "g", 'ButtonDownFcn', @mouseClick); %preN1 amp
                hold(app.UIAxes4, 'off')
            end

            if app.PlotBaselineCheckBox.Value == true
                hold(app.UIAxes4, 'on')
                yline(app.UIAxes4, table2array(app.activeData.waveIdata(selWv, {'Baseline'})), 'r--');
                hold(app.UIAxes4, 'off')
            end

            %add code to plot area under wave I
            if app.PlotwaveIareaCheckBox.Value == true
%                 %need to catch error thrown when any of the points are not
%                 %specified 
%                 waveIbegin = table2array(app.activeData.waveIdata(selWv,9));
%                 waveIend = table2array(app.activeData.waveIdata(selWv, 4));
%                 waveIarea = app.allPeaks(waveIbegin:waveIend, selWv);
%                 AUC = table2array(app.activeData.waveIdata(selWv, {'AUC wave I'}));
%                 if AUC == 0
%                     AUC = cumtrapz(app.t(waveIbegin:waveIend), ...
%                         waveIarea);
%                     AUC = sum(abs(AUC));
%                 end
%                 hold(app.UIAxes4, 'on')
%                 area(app.UIAxes4, app.t(waveIbegin:waveIend),...
%                     waveIarea, table2array(app.activeData.waveIdata(selWv,8)), "FaceColor", "#A8A8A8");
% %                 area(app.UIAxes4, app.t(waveIbegin:waveIend),...
% %                     waveIarea, "FaceColor", "#A8A8A8");
%                 hold(app.UIAxes4, 'on')
%                 plot(app.UIAxes4, [app.t(waveIbegin) app.t(waveIend)], ...
%                     [table2array(app.activeData.waveIdata(selWv,8)) table2array(app.activeData.waveIdata(selWv, 3))], 'r');
%                 hold(app.UIAxes4, 'on')
%                 text(app.UIAxes4, app.t(table2array(app.activeData.waveIdata(selWv,2))), ...
%                     (min(table2array(app.activeData.waveIdata(selWv,8)), ...
%                     table2array(app.activeData.waveIdata(selWv,3)))-0.1), num2str(AUC),...
%                     "HorizontalAlignment","center");
%                 hold(app.UIAxes4, 'off')
%                 app.WaveIareaEditField.Value = AUC;
%                 app.activeData.waveIdata(selWv, {'AUC wave I'}) = {AUC};
%                 refreshTable(app);
                plotArea(app);
            end
            
            function mouseClick(~, event)
                %get selection type
                %left click = 'normal', right click = 'alt', shift+left = 'extend'
                selType = get(app.UIFigure,'SelectionType');
                
                %get coords of mouse click
                coords = event.IntersectionPoint;
                
                %display coordinates
                app.LatencyEditField.Value = coords(1);
                app.AmplitudeEditField.Value = coords(2);
                
                %get the waveform plotted
                selWv = app.WaveformplottedSpinner.Value;
                
                %update activeData
                if strcmpi(selType, 'normal') %if left click, update N1
                    app.activeData.waveIdata(selWv, {'N1 amplitude (µV)'}) = {coords(2)};
                    app.activeData.waveIdata(selWv, {'Wave I latency (ms)'}) = {coords(1)};
                    %update Nidx based on selected point and update activeData
                    Nidx = round(coords(1)/0.04096);
                    app.activeData.waveIdata(selWv, {'N1 index'}) = {Nidx};
                elseif strcmpi(selType, 'alt') %if right click, update P1
                    app.activeData.waveIdata(selWv, {'P1 amplitude (µV)'}) = {coords(2)};
                    %update Pidx based on selected point and update activeData
                    Pidx = round(coords(1)/0.04096);
                    app.activeData.waveIdata(selWv, {'P1 index'}) = {Pidx};
                elseif strcmpi(selType, 'extend') %if double click, update preN1
                    app.activeData.waveIdata(selWv, {'preN1'}) = {coords(2)};
                    %update preN1idx based on selected point
                    preNidx = round(coords(1)/0.04096);
                    app.activeData.waveIdata(selWv, {'preN1 index'}) = {preNidx};
                end
                
                %calculate new Wave I amplitude (N1-P1)
                Nnew = table2array(app.activeData.waveIdata(selWv, {'N1 amplitude (µV)'}));
                Pnew = table2array(app.activeData.waveIdata(selWv, {'P1 amplitude (µV)'}));
                Wnew = Nnew-Pnew;
                app.activeData.waveIdata(selWv, {'Wave I amplitude (µV)'}) = {Wnew};

                %calculate new Wave I AUC
                if table2array(app.activeData.waveIdata(selWv, {'N1 index'})) > ...
                        table2array(app.activeData.waveIdata(selWv, {'P1 index'}))
                    AUC = 0;
                else
                    waveIbegin = table2array(app.activeData.waveIdata(selWv, {'preN1 index'}));
                    waveIend = table2array(app.activeData.waveIdata(selWv, {'P1 index'}));
                    waveIarea = app.allPeaks(waveIbegin:waveIend, selWv);
                    AUC = cumtrapz(app.t(waveIbegin:waveIend), ...
                        abs(waveIarea));
                    AUC = sum(AUC);
                end
                app.activeData.waveIdata(selWv, {'AUC wave I'}) = {AUC};
                
                %add in functionality to automatically update wave I points
                if app.WaveIPointsCheckBox.Value == true
                    updateOverlay(app);
                    updateSingle(app);
                    updateTile(app);
                end
                
%                 %refresh data table
%                 refreshTable(app);
%                 updateAmp(app);
                
                %update data
                value = app.DatasetsloadedListBox.Value; %select data file
                valInd = find(strcmp(app.DatasetsloadedListBox.Items, value)); %get index of list to retrieve other data
                app.allData(valInd).waveIdata = app.activeData.waveIdata;
                
                %refresh data table
                refreshTable(app);
                updateAmp(app);
                
                
            end
        end

        %% plot wave I area
        function plotArea(app)
            %need to catch error thrown when any of the points are not
            %specified 
            selWv = app.WaveformplottedSpinner.Value;
            waveIbegin = table2array(app.activeData.waveIdata(selWv,9));
            waveIend = table2array(app.activeData.waveIdata(selWv, 4));
            waveIarea = app.allPeaks(waveIbegin:waveIend, selWv);
            AUC = table2array(app.activeData.waveIdata(selWv, {'AUC wave I'}));
            if AUC == 0
                if waveIbegin == waveIend
                    AUC = 0;
                else
                    AUC = cumtrapz(app.t(waveIbegin:waveIend), ...
                        abs(waveIarea));
                    AUC = sum(AUC);
                end
            end
            hold(app.UIAxes4, 'on')
            area(app.UIAxes4, app.t(waveIbegin:waveIend),...
                waveIarea, table2array(app.activeData.waveIdata(selWv,8)), "FaceColor", "#A8A8A8");
%                 area(app.UIAxes4, app.t(waveIbegin:waveIend),...
%                     waveIarea, "FaceColor", "#A8A8A8");
%             hold(app.UIAxes4, 'on')
%             plot(app.UIAxes4, [app.t(waveIbegin) app.t(waveIend)], ...
%                 [table2array(app.activeData.waveIdata(selWv,8)) table2array(app.activeData.waveIdata(selWv, 3))], 'r');
            hold(app.UIAxes4, 'on')
            text(app.UIAxes4, app.t(table2array(app.activeData.waveIdata(selWv,2))), ...
                (min(table2array(app.activeData.waveIdata(selWv,8)), ...
                table2array(app.activeData.waveIdata(selWv,3)))-0.1), num2str(AUC),...
                "HorizontalAlignment","center");
            hold(app.UIAxes4, 'off')
            app.WaveIareaEditField.Value = AUC;
            app.activeData.waveIdata(selWv, {'AUC wave I'}) = {AUC};
            refreshTable(app);
        end
        
        %% compare plot
        function updateCompare(app)
            %generate plot with several stimulus levels for a given
            %frequency, spaced out (non-overlapping), for 2-3 different
            %runs (different animals, different timepoints, etc.)
            %want to add ability to select which datasets to include in the
            %plot rather than plot all.
            
            %set colors
            colr = {app.C1, app.C2, ...
                app.C3};

            % get waveforms for each animal/conditon into separate arrays
            for i = 1:length(app.allData)
                [~, numStims] = size(app.allData(i).Waveforms);
                if numStims > 5
                    samps(i).WF = app.allData(i).Waveforms(:,[1:5]);
                    %samps(i).stims = app.allData(i).stimlevs(:,[1:6]);
                else
                    samps(i).WF = app.allData(i).Waveforms(:,[1:numStims]);
                end
            end

            %modify each to add a certain value to each amplitude for an individual
            %Add 0 to lowest stimulus waveform, add 3 to all
            %values in next stimulus level waveform, add 6 to all values in next waveform, etc.
            for i = 1:length(samps)
                [~, numWFs] = size(samps(i).WF);
                for col = 1:numWFs
                    samps(i).WF(:,col) = samps(i).WF(:,col) + ((numWFs-col)*3);
                end
            end

            %plot the data
            for i = 1:length(samps)
                if length(samps) <= 3
                    plot(app.UIAxes5, app.t(app.idxBegin:app.idxEnd),...
                        samps(i).WF(app.idxBegin:app.idxEnd, :), ...
                        'Color', colr{i}, 'LineWidth', app.LineWidthSpinner.Value, ...
                        'DisplayName', strrep(app.DatasetsloadedListBox.Items{i}, '.txt', ''));
                    hold(app.UIAxes5, 'on')
                else                    
                    plot(app.UIAxes5, app.t(app.idxBegin:app.idxEnd),...
                        samps(i).WF(app.idxBegin:app.idxEnd, :), ...
                        'LineWidth', app.LineWidthSpinner.Value, ...
                        'DisplayName', strrep(app.DatasetsloadedListBox.Items{i}, '.txt', ''));
                    hold(app.UIAxes5, 'on')
                end

            end
            hold(app.UIAxes5, 'off')
            set(app.UIAxes5, 'YColor', 'k', 'yticklabel', [], 'YTick', 0:1:2, ...
                'XMinorTick', 'on', 'TickLength', [0.025,0.01], 'TickDir', 'both', ...
                'XTick', 0:1:app.EndTimepointEditField.Value)
            app.UIAxes5.XLabel.String = "Latency (ms)";
            app.UIAxes5.YLabel.String = "";
            app.UIAxes5.XLim = [app.BeginTimepointEditField.Value app.EndTimepointEditField.Value];

            %need to figure out how to get one line for each animal
            %instead of for each line
            %create legend using legendUnq from 
            %https://www.mathworks.com/matlabcentral/fileexchange/67646-legendunq
            legend(legendUnq(app.UIAxes5), 'Location', 'northoutside', 'NumColumns', 2);

            %turn legend on or off based off options
            if app.FigLegendsCheckBox.Value == true
                legend(app.UIAxes5, 'show');
            elseif app.FigLegendsCheckBox.Value == false
                legend(app.UIAxes5, 'off');
            end
        end
        
        %% wave I amplitude and latency plots
        function updateAmp(app)
            %swave I amp plot - want to add curve fitting
            app.UIAxes2.Title.String = app.activeName;
            amps = flip(table2array(app.activeData.waveIdata(:,5)));
            stims = flip(app.activeData.stimlevs);
            levs = cellfun(@(x) strsplit(x, '-'), stims, 'UniformOutput', false);
            x = [];
            for i=1:length(levs)
                l = convertCharsToStrings(levs{i}{1});
                x = vertcat(x, l); %#ok<AGROW> 
            end
            x = str2double(x);
            plot(app.UIAxes2, x, amps, 'ko', 'MarkerSize', 8, 'MarkerFaceColor', 'k')
            app.UIAxes2.XMinorTick = 'on';
            app.UIAxes2.XLim = [0 100];
            app.UIAxes2.YLim = [0 4];

            %wave I latency plot
            app.UIAxes3.Title.String = app.activeName;
            lats = flip(table2array(app.activeData.waveIdata(:,6)));
            plot(app.UIAxes3, x, lats, 'ko', 'MarkerSize', 8, 'MarkerFaceColor', 'k')
            app.UIAxes3.XMinorTick = 'on';
            app.UIAxes3.XLim = [0 100];
            app.UIAxes3.YLim = [0 3];
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'ABR Visualization App';
            movegui(app.UIFigure, "center");
            %datacursormode(app.UIFigure, 'on')
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {660, 660};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {239, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %Get file names for the ABR data files by pressing Load Data
            %button. Opens UI dialog box to allow navigating directories
            app.dataFolder = uigetdir('C:\', 'Select folder with files for analysis'); %use UI to select directory
            %want to set default directory to home dir
            [dataFiles, names] = getFileNames(app.dataFolder); %get names of ABR data files            
            
            %run identifyPeaks and store data in allData
            app.allData = identifyPeaks(dataFiles); 
            %possibly transform all data here so allData has plottable
            %waveform data. Will need to modify rest of code if so
            for i = 1:length(app.allData)
                app.allData(i).stimlevs = app.allData(i).Waveforms.Properties.VariableNames;
                app.allData(i).Waveforms = (table2array(app.allData(i).Waveforms)).*1000000;
            end
            
            %populate list box
            app.DatasetsloadedListBox.Items = names;
        end

        % Value changed function: DatasetsloadedListBox
        function DatasetsloadedListBoxValueChanged(app, event)
            value = app.DatasetsloadedListBox.Value; %select data file
            valInd = find(strcmp(app.DatasetsloadedListBox.Items, value)); %get index of list to retrieve other data
            
            %make the selected data the activeData
            app.activeData = app.allData(valInd);
            app.activeName = strrep(strrep(app.activeData.Name, 'r_', ''), '_', '-');
            app.DataselectedEditField.Value = app.activeName; %display selected data name
            app.WaveformplottedSpinner.Value = 1;
            
            %Populate wave I data table
            refreshTable(app);
            
            %Plot data
            updatePlotData(app);
            updateTile(app);
            updateOverlay(app);
            updateSingle(app);
            updateAmp(app);
            updateCompare(app);
            
        end

        % Value changed function: WaveIPointsCheckBox
        function WaveIPointsCheckBoxValueChanged(app, event)
            updateTile(app); %change if wave I points are plotted
            updateOverlay(app);
            updateSingle(app);
        end

        % Value changed function: PlotwaveIareaCheckBox
        function PlotwaveIareaCheckBoxValueChanged(app, event)
            updateSingle(app);
            
        end

        % Value changed function: EndTimepointEditField
        function EndTimepointEditFieldValueChanged(app, event)
            updateTile(app); %change end timepoint on x axis
            updateOverlay(app);
            updateSingle(app);
            updateCompare(app);
        end

        % Value changed function: BeginTimepointEditField
        function BeginTimepointEditFieldValueChanged(app, event)
            updateTile(app); %change begin timepoint on x axis
            updateOverlay(app);
            updateSingle(app);
            updateCompare(app);
        end

        % Value changed function: LinkaxesCheckBox
        function LinkaxesCheckBoxValueChanged(app, event)
            updateTile(app); %specify if axes in IndWF should be linked
        end

        % Value changed function: PlotBaselineCheckBox
        function PlotBaselineCheckBoxValueChanged(app, event)
            updateTile(app); %change if baseline is plotted
            updateCompare(app);
        end

        % Value changed function: WaveformplottedSpinner
        function WaveformplottedSpinnerValueChanged(app, event)
            updateSingle(app); %change which waveform is in the single plott
        end

        % Button pushed function: SavePlotsButton
        function SavePlotsButtonPushed(app, event)
            %get save directory
            saveDir = uigetdir();
            plotName = strcat(saveDir, '/', app.activeName);
            %need to add in code to save all plots
            exportgraphics(app.UIAxes, strcat(plotName, '_overlayPlot.tif'));
            exportgraphics(app.UIAxes2, strcat(plotName, '_waveIplot.tif'));
            exportgraphics(app.UIAxes5, strcat(saveDir, '/compareplot.eps'));
        end

        % Button pushed function: SaveTablesButton
        function SaveTablesButtonPushed(app, event)
            %get save directory
            saveDir = uigetdir();
            tableName = strcat(saveDir, '/', app.activeName, '.xlsx');
            writetable(app.UITable.Data, tableName);
        end

        % Value changed function: FigLegendsCheckBox
        function FigLegendsCheckBoxValueChanged(app, event)
            updateCompare(app);
            updateOverlay(app);
        end

        % Button pushed function: Color1Button
        function Color1ButtonPushed(app, event)
            app.C1 = uisetcolor([0 0 1], 'Select a color');
            app.C1Field.BackgroundColor = app.C1;
            updateCompare(app);
        end

        % Button pushed function: Color2Button
        function Color2ButtonPushed(app, event)
            app.C2 = uisetcolor([1 0 1], 'Select a color');
            app.C2Field.BackgroundColor = app.C2;
            updateCompare(app);
        end

        % Button pushed function: Color3Button
        function Color3ButtonPushed(app, event)
            app.C3 = uisetcolor([0 0 0], 'Select a color');
            app.C3Field.BackgroundColor = app.C3;
            updateCompare(app);
        end

        % Value changed function: LineWidthSpinner
        function LineWidthSpinnerValueChanged(app, event)
            updateCompare(app);
            updateOverlay(app);
            updateTile(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %get save directory
            saveDir = uigetdir();
            
            %save plot as eps, may add option to select file type
            exportgraphics(app.UIAxes5, strcat(saveDir, '/compareplot.eps'));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlotOptionsPanel = uipanel(app.LeftPanel);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.FigLegendsCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.FigLegendsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FigLegendsCheckBoxValueChanged, true);
            app.FigLegendsCheckBox.Text = 'Fig. Legends';
            app.WaveIPointsCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.WaveIPointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @WaveIPointsCheckBoxValueChanged, true);
            app.WaveIPointsCheckBox.Text = 'Wave I Points';
            app.BeginTimepointEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.BeginTimepointEditFieldLabel.Text = 'Begin Timepoint';
            app.BeginTimepointEditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.BeginTimepointEditField.Limits = [0 12];
            app.BeginTimepointEditField.ValueChangedFcn = createCallbackFcn(app, @BeginTimepointEditFieldValueChanged, true);
            app.EndTimepointEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.EndTimepointEditFieldLabel.Text = 'End Timepoint';
            app.EndTimepointEditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.EndTimepointEditField.Limits = [0 12];
            app.EndTimepointEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimepointEditFieldValueChanged, true);
            app.EndTimepointEditField.Value = 6;
            app.DataselectedEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.DataselectedEditFieldLabel.Text = 'Data selected';
            app.DataselectedEditField = uieditfield(app.PlotOptionsPanel, 'text');
            app.LinkaxesCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.LinkaxesCheckBox.ValueChangedFcn = createCallbackFcn(app, @LinkaxesCheckBoxValueChanged, true);
            app.LinkaxesCheckBox.Text = 'Link axes';
            app.PlotBaselineCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.PlotBaselineCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotBaselineCheckBoxValueChanged, true);
            app.PlotBaselineCheckBox.Text = 'Plot Baseline';
            app.WaveformplottedSpinnerLabel = uilabel(app.PlotOptionsPanel);
            app.WaveformplottedSpinnerLabel.Text = 'Waveform plotted';
            app.WaveformplottedSpinner = uispinner(app.PlotOptionsPanel);
            app.WaveformplottedSpinner.Limits = [1 Inf];
            app.WaveformplottedSpinner.ValueChangedFcn = createCallbackFcn(app, @WaveformplottedSpinnerValueChanged, true);
            app.WaveformplottedSpinner.Value = 1;
            app.LatencyEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.LatencyEditFieldLabel.Text = 'Latency';
            app.LatencyEditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.AmplitudeEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.Color1Button = uibutton(app.PlotOptionsPanel, 'push');
            app.Color1Button.ButtonPushedFcn = createCallbackFcn(app, @Color1ButtonPushed, true);
            app.Color1Button.Text = 'Color1';
            app.Color2Button = uibutton(app.PlotOptionsPanel, 'push');
            app.Color2Button.ButtonPushedFcn = createCallbackFcn(app, @Color2ButtonPushed, true);
            app.Color2Button.Text = 'Color2';
            app.Color3Button = uibutton(app.PlotOptionsPanel, 'push');
            app.Color3Button.ButtonPushedFcn = createCallbackFcn(app, @Color3ButtonPushed, true);
            app.Color3Button.Text = 'Color3';
            app.C1Field = uieditfield(app.PlotOptionsPanel, 'text');
            app.C2Field = uieditfield(app.PlotOptionsPanel, 'text');
            app.C3Field = uieditfield(app.PlotOptionsPanel, 'text');
            app.LineWidthSpinnerLabel = uilabel(app.PlotOptionsPanel);
            app.LineWidthSpinnerLabel.Text = 'Line Width';
            app.LineWidthSpinner = uispinner(app.PlotOptionsPanel);
            app.LineWidthSpinner.Step = 0.5;
            app.LineWidthSpinner.Limits = [0.5 Inf];
            app.LineWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @LineWidthSpinnerValueChanged, true);
            app.LineWidthSpinner.Value = 1;
            app.PlotwaveIareaCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.PlotwaveIareaCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotwaveIareaCheckBoxValueChanged, true);
            app.PlotwaveIareaCheckBox.Text = 'Plot wave I area';
            app.WaveIareaEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.WaveIareaEditFieldLabel.Text = 'Wave I area';
            app.WaveIareaEditField = uieditfield(app.PlotOptionsPanel, 'numeric');
            app.DataPanel = uipanel(app.LeftPanel);
            app.DataPanel.Title = 'Data';
            app.LoadDataButton = uibutton(app.DataPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SavePlotsButton = uibutton(app.DataPanel, 'push');
            app.SavePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotsButtonPushed, true);
            app.SavePlotsButton.Text = 'Save Plots';
            app.SaveTablesButton = uibutton(app.DataPanel, 'push');
            app.SaveTablesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTablesButtonPushed, true);
            app.SaveTablesButton.Text = 'Save Tables';
            app.DatasetsloadedListBoxLabel = uilabel(app.DataPanel);
            app.DatasetsloadedListBoxLabel.Text = 'Data sets loaded';
            app.DatasetsloadedListBox = uilistbox(app.DataPanel);
            app.DatasetsloadedListBox.Items = {};
            app.DatasetsloadedListBox.ValueChangedFcn = createCallbackFcn(app, @DatasetsloadedListBoxValueChanged, true);
            app.DatasetsloadedListBox.Value = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.OverlayTab = uitab(app.TabGroup);
            app.OverlayTab.Title = 'Overlay';
            app.UIAxes = uiaxes(app.OverlayTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'Amplitude (µV)')
            zlabel(app.UIAxes, 'Z')
            app.SelectedWaveTab = uitab(app.TabGroup);
            app.SelectedWaveTab.Title = 'Selected Wave';
            app.UIAxes4 = uiaxes(app.SelectedWaveTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'Latency (ms)')
            ylabel(app.UIAxes4, 'Amplitude (µV)')
            zlabel(app.UIAxes4, 'Z')
            app.WaveIamp = uitab(app.TabGroup);
            app.WaveIamp.Title = 'WaveIamp';
            app.UIAxes2 = uiaxes(app.WaveIamp);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Stimulus level (dB SPL)')
            ylabel(app.UIAxes2, 'Wave I amplitdue (µV)')
            zlabel(app.UIAxes2, 'Z')
            app.WaveIlatTab = uitab(app.TabGroup);
            app.WaveIlatTab.Title = 'WaveIlat';
            app.UIAxes3 = uiaxes(app.WaveIlatTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'Stimulus level (dB SPL)')
            ylabel(app.UIAxes3, 'Wave I latency (ms)')
            zlabel(app.UIAxes3, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Stimulus level (dB)'; 'N1 amp'; 'P1 amp'; 'Wave I amp'; 'Wave I lat.'; 'AUC'; 'Wave I width'};
            app.UITable.RowName = {};
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.IndWFsTab = uitab(app.TabGroup2);
            app.IndWFsTab.Title = 'IndWFs';
            app.CompTab = uitab(app.TabGroup2);
            app.CompTab.Title = 'Comp';
            app.UIAxes5 = uiaxes(app.CompTab);
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.SaveButton = uibutton(app.CompTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ABRapp_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end